classdef GrayScottController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadmodelMenu                  matlab.ui.container.Menu
        SavemodelMenu                  matlab.ui.container.Menu
        SavevideoMenu                  matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ManualMenu                     matlab.ui.container.Menu
        AboutPPPMenu                   matlab.ui.container.Menu
        AbouttheAppMenu                matlab.ui.container.Menu
        GrayScottControllerPanel       matlab.ui.container.Panel
        SolverButtonGroup              matlab.ui.container.ButtonGroup
        FullRadioButton                matlab.ui.control.RadioButton
        StencilRadioButton             matlab.ui.control.RadioButton
        SparseRadioButton              matlab.ui.control.RadioButton
        Lamp                           matlab.ui.control.Lamp
        D_vEditField                   matlab.ui.control.NumericEditField
        D_vLabel                       matlab.ui.control.Label
        D_uEditField                   matlab.ui.control.NumericEditField
        D_uLabel                       matlab.ui.control.Label
        kSlider                        matlab.ui.control.Slider
        kSliderLabel                   matlab.ui.control.Label
        FSlider                        matlab.ui.control.Slider
        FSliderLabel                   matlab.ui.control.Label
        BoundaryconditionsButtonGroup  matlab.ui.container.ButtonGroup
        DirichletButton                matlab.ui.control.RadioButton
        NeumannButton                  matlab.ui.control.RadioButton
        PeriodicButton                 matlab.ui.control.RadioButton
        EquationLabel                  matlab.ui.control.Label
        dotVD_vnabla2VUV2FkVLabel      matlab.ui.control.Label
        dotUD_unabla2UUV2F1ULabel      matlab.ui.control.Label
        RecordButton                   matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        isRunning
        plotFig
        p
        grid 
        model
        plotAx
        plotImg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appFile = matlab.desktop.editor.getActiveFilename;  
            if isempty(appFile)
                
                appFile = mfilename('fullpath');
            end
            
            appDir  = fileparts(appFile);       
            rootDir = fileparts(appDir);        
            
            addpath(genpath(rootDir));

            app.isRunning = false;

            app.p = defaultParams()
            app.p.plotField = "u"; % GUI default
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;
            app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   plotEvery = %d", ...
                app.p.F, app.p.k, app.p.plotEvery);

            app.grid = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);

            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name' , 'Gray-Scott Plot', 'NumberTitle','off');
            else
                figure(app.plotFig); % bring it to front
            end

            [U, ~] = app.model.getFields2D();
            
            figure(app.plotFig);
            clf(app.plotFig);
            
            app.plotAx = axes(app.plotFig);
            app.plotImg = imagesc(app.plotAx, U);
            
            axis(app.plotAx, 'image');
            set(app.plotAx, 'YDir', 'normal');
            colorbar(app.plotAx);
            title(app.plotAx, "Initial U");

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.isRunning = true;

            app.RunButton.Enable = "off";
            app.PauseButton.Enable = "on";

            % Safety: if plot figure was closed, recreate it
            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name','Gray–Scott Plot', 'NumberTitle','off');
                app.plotAx  = axes(app.plotFig);
                [U, ~] = app.model.getFields2D();
                app.plotImg = imagesc(app.plotAx, U);
                axis(app.plotAx, 'image');
                set(app.plotAx, 'YDir', 'normal');
                colorbar(app.plotAx);
            end
        
            % Main loop (no timer): runs until Pause or app closed
            while app.isRunning && isvalid(app.UIFigure)
        
                % Make sure model uses latest F,k (runtime tuning)
                app.model.p.F = app.p.F;
                app.model.p.k = app.p.k;
        
                % Step a small batch per UI refresh
                nBatch = 20;
                info = struct();
                for i = 1:nBatch
                    info = app.model.step();
                    if isfield(info,'hasNaNInf') && info.hasNaNInf
                        break;
                    end
                end
                app.RunButton.Enable = "on";
                % Plot update every plotEvery steps
                if app.p.plotEvery > 0 && mod(app.model.n, app.p.plotEvery) == 0
                    [U, ~] = app.model.getFields2D();
                    if ~isempty(app.plotImg) && isvalid(app.plotImg)
                        app.plotImg.CData = U;
                        title(app.plotAx, sprintf("U (n=%d, t=%.3g)", app.model.n, app.model.t));
                    end
                end
        
                % Stop on NaN/Inf
                if isfield(info,'hasNaNInf') && info.hasNaNInf
                    app.isRunning = false;
                    app.Label.Text = "Stopped: NaN/Inf detected.";
                    break;
                end
        
                % Allow UI callbacks to run (Pause/Reset/close)
                drawnow limitrate;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.isRunning = false;

            app.RunButton.Enable = "on";

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.isRunning = false;

            app.RunButton.Enable = "on";
            
            % Recreate model using current parameters
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);
            
            % Sync model params (in case constructor copies p)
            app.model.p.F = app.p.F;
            app.model.p.k = app.p.k;
            
            % Redraw initial U using fast update
            [U, ~] = app.model.getFields2D();
            if ~isempty(app.plotImg) && isvalid(app.plotImg)
                app.plotImg.CData = U;
                title(app.plotAx, sprintf("Initial U (Reset) (n=%d, t=%.3g)", app.model.n, app.model.t));
                drawnow;
            end
            app.Label.Text = sprintf("F = %.5f   |   k = %.5f", app.p.F, app.p.k);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isRunning = false;

            try
                if ~isempty(app.plotFig) && isvalid(app.plotFig)
                    close(app.plotFig);
                end
            catch
            end
            
            delete(app)
            
        end

        % Value changed function: FSlider
        function FSliderValueChanged(app, event)
            app.p.F = app.FSlider.Value;
            if ~isempty(app.model)
                app.model.p.F = app.p.F;
            end

            app.Label.Text = sprintf("F = %.5f   |   k = %.5f", app.p.F, app.p.k);
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            app.p.k = app.kSlider.Value;
            if ~isempty(app.model)
                app.model.p.k = app.p.k;
            end

            app.Label.Text = sprintf("F = %.5f   |   k = %.5f", app.p.F, app.p.k);
        end

        % Selection changed function: SolverButtonGroup
        function SolverButtonGroupSelectionChanged(app, event)

            % Stop running if active
            app.isRunning = false;
            app.RunButton.Enable = "on";
        
            selected = app.SolverButtonGroup.SelectedObject;
        
            if selected == app.SparseRadioButton
                app.p.diffusionMode = "matrix";
            elseif selected == app.StencilRadioButton
                app.p.diffusionMode = "stencil";
            elseif selected == app.FullRadioButton
                app.p.diffusionMode = "full";
            else
                app.p.diffusionMode = "matrix";
            end
        
            % Changing solver triggers reset
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);
        
            [U, ~] = app.model.getFields2D();
            app.plotImg.CData = U;
            title(app.plotAx, sprintf("Initial U (%s) (n=%d, t=%.3g)", ...
                app.p.diffusionMode, app.model.n, app.model.t));
            drawnow;
        
            app.Label.Text = sprintf("F = %.5f   |   k = %.5f   |   mode = %s", ...
                app.p.F, app.p.k, app.p.diffusionMode);

            disp("op.mode = " + string(app.model.op.mode))


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadmodelMenu = uimenu(app.FileMenu);
            app.LoadmodelMenu.Text = 'Load model';
            app.SavemodelMenu = uimenu(app.FileMenu);
            app.SavemodelMenu.Text = 'Save model';
            app.SavevideoMenu = uimenu(app.FileMenu);
            app.SavevideoMenu.Separator = 'on';
            app.SavevideoMenu.Text = 'Save video';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.Text = 'Manual';
            app.AboutPPPMenu = uimenu(app.HelpMenu);
            app.AboutPPPMenu.Text = 'About PPP';
            app.AbouttheAppMenu = uimenu(app.HelpMenu);
            app.AbouttheAppMenu.Text = 'About the App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GrayScottControllerPanel = uipanel(app.UIFigure);
            app.GrayScottControllerPanel.Title = 'Gray-Scott-Controller';
            app.RunButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.GrayScottControllerPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RecordButton = uibutton(app.GrayScottControllerPanel, 'state');
            app.RecordButton.Text = 'Record';
            app.dotUD_unabla2UUV2F1ULabel = uilabel(app.GrayScottControllerPanel);
            app.dotUD_unabla2UUV2F1ULabel.Interpreter = 'latex';
            app.dotUD_unabla2UUV2F1ULabel.Text = '$\dot U = D_u \nabla ^2 U - UV^2  + F(1-U)$';
            app.dotVD_vnabla2VUV2FkVLabel = uilabel(app.GrayScottControllerPanel);
            app.dotVD_vnabla2VUV2FkVLabel.Interpreter = 'latex';
            app.dotVD_vnabla2VUV2FkVLabel.Text = '$\dot V = D_v \nabla ^2 V + UV^2  - (F+k)V$';
            app.EquationLabel = uilabel(app.GrayScottControllerPanel);
            app.EquationLabel.Text = 'Equation';
            app.BoundaryconditionsButtonGroup = uibuttongroup(app.GrayScottControllerPanel);
            app.BoundaryconditionsButtonGroup.Title = 'Boundary conditions';
            app.PeriodicButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.PeriodicButton.Value = true;
            app.NeumannButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.NeumannButton.Text = 'Neumann';
            app.DirichletButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.DirichletButton.Text = 'Dirichlet';
            app.FSliderLabel = uilabel(app.GrayScottControllerPanel);
            app.FSliderLabel.Text = 'F';
            app.FSlider = uislider(app.GrayScottControllerPanel);
            app.FSlider.Limits = [0 0.1];
            app.FSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.FSlider.ValueChangedFcn = createCallbackFcn(app, @FSliderValueChanged, true);
            app.FSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.kSliderLabel = uilabel(app.GrayScottControllerPanel);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.GrayScottControllerPanel);
            app.kSlider.Limits = [0 0.1];
            app.kSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.D_uLabel = uilabel(app.GrayScottControllerPanel);
            app.D_uLabel.Interpreter = 'latex';
            app.D_uLabel.Text = '$D_u$';
            app.D_uEditField = uieditfield(app.GrayScottControllerPanel, 'numeric');
            app.D_uEditField.Value = 2e-05;
            app.D_vLabel = uilabel(app.GrayScottControllerPanel);
            app.D_vLabel.Interpreter = 'latex';
            app.D_vLabel.Text = '$D_v$';
            app.D_vEditField = uieditfield(app.GrayScottControllerPanel, 'numeric');
            app.D_vEditField.Value = 1e-05;
            app.Lamp = uilamp(app.GrayScottControllerPanel);
            app.SolverButtonGroup = uibuttongroup(app.GrayScottControllerPanel);
            app.SolverButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolverButtonGroupSelectionChanged, true);
            app.SolverButtonGroup.Title = 'Solver';
            app.SparseRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.SparseRadioButton.Text = 'Sparse (matrix)';
            app.SparseRadioButton.Value = true;
            app.StencilRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.StencilRadioButton.Text = 'Stencil';
            app.FullRadioButton = uiradiobutton(app.SolverButtonGroup);
            app.FullRadioButton.Text = 'Full (dense)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrayScottController

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end